classdef AcquisitionDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AcquisitionDialogUIFigure      matlab.ui.Figure
        MeasurementSettingsPanel       matlab.ui.container.Panel
        ModeSwitch                     matlab.ui.control.Switch
        ModeSwitchLabel                matlab.ui.control.Label
        TimeLeftsecEditField           matlab.ui.control.NumericEditField
        TimeLeftsecEditFieldLabel      matlab.ui.control.Label
        CurrentCountEditField          matlab.ui.control.NumericEditField
        CurrentCountEditFieldLabel     matlab.ui.control.Label
        AcqusitionCountEditField       matlab.ui.control.NumericEditField
        AcqusitionCountEditFieldLabel  matlab.ui.control.Label
        TimesecEditField               matlab.ui.control.NumericEditField
        TimesecEditFieldLabel          matlab.ui.control.Label
        AverageNumberEditField         matlab.ui.control.NumericEditField
        IntervalsecLabel               matlab.ui.control.Label
        SystemReadyLamp                matlab.ui.control.Lamp
        SystemReadyLampLabel           matlab.ui.control.Label
        THzLamp                        matlab.ui.control.Lamp
        THzLampLabel                   matlab.ui.control.Label
        MeasurementDetailsPanel        matlab.ui.container.Panel
        NumericValueEditField_2        matlab.ui.control.NumericEditField
        NumericValueEditFieldLabel     matlab.ui.control.Label
        Metadata2EditField             matlab.ui.control.EditField
        Metadata2EditFieldLabel        matlab.ui.control.Label
        NumericValueEditField_1        matlab.ui.control.NumericEditField
        NumericValueEditField_2Label   matlab.ui.control.Label
        Metadata1EditField             matlab.ui.control.EditField
        Metadata1EditFieldLabel        matlab.ui.control.Label
        ModeDropDown                   matlab.ui.control.DropDown
        ModeDropDownLabel              matlab.ui.control.Label
        DescriptionEditField           matlab.ui.control.EditField
        DescriptionEditFieldLabel      matlab.ui.control.Label
        SampleEditField                matlab.ui.control.EditField
        SampleEditFieldLabel           matlab.ui.control.Label
        STOPButton                     matlab.ui.control.Button
        ACQUIREButton                  matlab.ui.control.Button
        ReferenceAcquisitionPanel      matlab.ui.container.Panel
        PumpLamp                       matlab.ui.control.Lamp
        PumpLampLabel                  matlab.ui.control.Label
        ReferenceLamp                  matlab.ui.control.Lamp
        ReferenceLampLabel             matlab.ui.control.Label
        BaselineLamp                   matlab.ui.control.Lamp
        BaselineLampLabel              matlab.ui.control.Label
        SubtractBaselineCheckBox       matlab.ui.control.CheckBox
        PumpCheckBox                   matlab.ui.control.CheckBox
        RemovePumpReferenceButton      matlab.ui.control.Button
        PumpReferenceButton            matlab.ui.control.Button
        RemoveReferenceButton          matlab.ui.control.Button
        ReferenceButton                matlab.ui.control.Button
        RemoveBaselineButton           matlab.ui.control.Button
        BaselineButton                 matlab.ui.control.Button
        EditField                      matlab.ui.control.EditField
        Image                          matlab.ui.control.Image
        ReadStatusButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
        MainApp % Main app
        numAcq % total number of acquisitions
        TcellAcq % Acquistion table cell
        matBaseline % Baseline matrix [time stamps; amplitudes]
        matReference % Reference matrix [time stamps; amplitudes]
        matPumpRef % Pump reference matrix [time stamps; amplitudes]
        processStop % Is Stop-button pressed?
    end
    
    methods (Access = private)
        
        function updateMain(app)
            % Call main app's public function
            %TcellAcq = app.TcellAcq;
            %updateTableRemote(app.MainApp,TcellAcq);
        end
      
        function updateTcellAcq(app,TcellCol)
            TcellAcq = app.TcellAcq;
            TcellAcq = [TcellAcq, TcellCol];
            app.TcellAcq = TcellAcq;
        end

        function matCurrent = addMeasurement(app) % single scan#
                numAcq = app.numAcq;
                numAcq = numAcq + 1;
                fig = app.AcquisitionDialogUIFigure;

                curCol = size(app.TcellAcq,2) + 1;
                TcellCol = cell(22,1);
                matMeas = readWaveform(app); % get the current waveform from TeraSmart
                
                % sampleName = app.SampleEditField.Value;
                % description = app.DescriptionEditField.Value;
                % mode = app.ModeDropDown.Value;
                % 
                % md1Des = app.Metadata1EditField.Value;
                % md2Des = app.Metadata2EditField.Value;
                % md1Val = app.NumericValueEditField_1.Value;
                % md2Val = app.NumericValueEditField_2.Value;
                % md3Val = [];
                % md4Val = [];
                % md5Val = [];
                % 
                % 
                % if ~isempty(md1Des)
                %     mdDescription = md1Des;
                % else
                %     mdDescription = [];
                % end
                % 
                % if ~isempty(md2Des)
                %     mdDescription = strcat(mdDescription,',',md2Des);
                % end
                % 
                % 
                % if isempty(md1Val)
                %     md1Val = [];
                % end
                % 
                % if isempty(md2Val)
                %     md2Val = [];
                % end
                % 
                % matBaseline = app.matBaseline;
                % matRefernece = app.matReference;
                % matPumpRef = app.matPumpRef;
                % 
                % % Baseline subtraction check
                % if app.SubtractBaselineCheckBox.Value
                % 
                %     if isempty(matBaseline)
                %         uialert(fig,'No valid baseline','Acquisition aborted');
                %         return;
                %     end
                % 
                %     if ~isempty(matRefernece)
                %         try
                %             matRefernece(2,:) = matRefernece(2,:) - matBaseline(2,:);
                %         catch
                %             uialert(fig,'Inconsist Waveform length','Baseline subtraction aborted');
                %             return;
                %         end
                %     end
                % 
                %     if ~isempty(matMeas)
                %         try
                %             matMeas(2,:) = matMeas(2,:) - matBaseline(2,:);
                %         catch
                %             uialert(fig,'Inconsist Waveform length','Baseline subtraction aborted');
                %             return;
                %         end
                %     end                    
                % 
                %     if app.PumpCheckBox.Value && ~isempty(matPumpRef)
                %         try
                %             matPumpRef(2,:) = matPumpRef(2,:) - matBaseline(2,:);
                %         catch
                %             uialert(fig,'Inconsist Waveform length','Baseline subtraction aborted');
                %             return;
                %         end
                %     end
                % end
                % 
                % 
                % dsDescription = "Sample"; % dataset description
                % ds1 = matMeas;
                % 
                % if ~isempty(matRefernece)
                %     ds2 = matRefernece;
                %     dsDescription = strcat(dsDescription,',',"Reference");
                % else
                %     ds2 = [];
                % end
                % 
                % if app.PumpCheckBox.Value && ~isempty(matPumpRef)
                %     ds3 = matRefernece;
                %     dsDescription = strcat(dsDescription,',',"PumpRef");
                % else
                %     ds3 = [];
                % end
                % 
                % ds4 = [];
                % 
                % TcellCol{1} = curCol;
                % TcellCol{2} = sampleName;
                % TcellCol{3} = description;
                % TcellCol{4} = 0; % Instrument profile
                % TcellCol{5} = 0; % User profile
                % TcellCol{6} = time; % measurement start time
                % TcellCol{7} = mode; % THz-TDS/THz-Imaging/Transmission/Reflection
                % TcellCol{8} = []; % coordinates
                % 
                % TcellCol{9} = mdDescription; % metadata description
                % TcellCol{10} = md1Val;
                % TcellCol{11} = md2Val;
                % TcellCol{12} = md3Val; 
                % TcellCol{13} = md4Val; 
                % TcellCol{14} = md5Val; 
                % 
                % TcellCol{15} = []; % not used
                % TcellCol{16} = []; % not used
                % TcellCol{17} = []; % not used
                % 
                % TcellCol{18} = dsDescription; % dataset description
                % TcellCol{19} = ds1;
                % TcellCol{20} = ds2;
                % TcellCol{21} = ds3; 
                % TcellCol{22} = ds4; 
                % 
                % matCurrent = ds1;
                % updateTcellAcq(app,TcellCol);
                % app.numAcq = numAcq;
        end
        
        function matMeas = readWaveform(app)
            measAverage = app.AverageNumberEditField.Value;
            measTime = app.TimesecEditField.Value;
            measCount = app.AcqusitionCountEditField.Value;
            measMode = app.ModeSwitch.Value;

            if isequal(measMode,'Count')
                cMode = true;
            else
                cMode = false;
            end
            
            %status = pyrunfile("connectSC.py","status");
            output = pyrunfile("getPulse_ml02.py","output", ...
                measurement_average = measAverage, measurement_time = measTime, ...
                measurement_count = measCount, count_mode = cMode);
            assignin("base","output",output);
            % % output = cell(output);
            % % out1 = cell(output{1});
            % % out2 = cell(output{2});
            % % vecTime = double(out1{:});
            % % vecAmp = double(out2{:});
            % % matMeas = [vecTime; vecAmp];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, Tcell)
            % Store main app object
            app.MainApp = mainapp;
            app.TcellAcq = Tcell;
            app.numAcq = 0;
        end

        % Value changed function: PumpCheckBox
        function PumpCheckBoxValueChanged(app, event)
            value = app.PumpCheckBox.Value;
            if value
                app.PumpReferenceButton.Visible = "on";
                app.RemovePumpReferenceButton.Visible = "on";
                app.PumpLampLabel.Visible = "on";
                app.PumpLamp.Visible = "on";
            else
                app.PumpReferenceButton.Visible = "off";
                app.RemovePumpReferenceButton.Visible = "off";
                app.PumpLampLabel.Visible = "off";
                app.PumpLamp.Visible = "off";
            end
        end

        % Close request function: AcquisitionDialogUIFigure
        function AcquisitionDialogUIFigureCloseRequest(app, event)
            % Enable Acquire button in main app, if the app is still open
            if isvalid(app.MainApp)
                app.MainApp.AcquirefromTeraSmartButton.Enable = "on";
                app.MainApp.DeployDataButton.Enable = "on";
                app.MainApp.ClearMemoryButton.Enable = "on";
            end

            % Delete the dialog box
            delete(app)            
        end

        % Button pushed function: ACQUIREButton
        function ACQUIREButtonPushed(app, event)
            app.SystemReadyLamp.Color = [0.85,0.33,0.10];
            app.SystemReadyLampLabel.Text = "Scaning...";
            app.processStop = false;
            fig = app.AcquisitionDialogUIFigure;
            drawnow

            % if app.MultipleAcquisitionCheckBox.Value
            %     timeInput = app.TimesecEditField.Value;
            %     numAcqInput = app.AcqusitionCountEditField.Value;
            % 
            %     if timeInput == 0 && numAcqInput == 0
            %         uialert(fig,'Invalid Multiple Acquisition Settings','Aborted');
            %         app.SystemReadyLamp.Color = "Green";
            %         app.SystemReadyLampLabel.Text = "Ready";
            %         return;
            %     end
            % 
            %     if timeInput ~= 0
            %         initTime = datetime;
            %         while ~app.processStop
            %             addMeasurement(app);
            %             timeLeft = timeInput - seconds(datetime - initTime);
            %             if timeLeft <= 0
            %                 app.processStop = true;
            %                 timeLeft = 0;
            %             end
            %             app.TimeLeftsecEditField.Value = timeLeft;
            %         end
            % 
            %     else % numAcqInput ~= 0
            %         while ~app.processStop
            %             addMeasurement(app);
            %             if numAcqInput < scanCnt
            %                 app.processStop = true;
            %             end
            %         end
            % 
            %     end
            % 
            % else
            %     addMeasurement(app);
            %     app.CurrentCountEditField.Value = scanCnt;
            % end

            addMeasurement(app);
            app.SystemReadyLamp.Color = "Green";
            app.SystemReadyLampLabel.Text = "Ready";
            drawnow

            % Update Main App table
            % updateTableRemote(app.MainApp,app.TcellAcq);
            
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.CurrentCountEditField.Value = 0;
            app.AcqusitionCountEditField.Value = 1;
            app.TimesecEditField.Value = 0;
            app.TimeLeftsecEditField.Value = 0;
            app.processStop = true;
        end

        % Button pushed function: BaselineButton
        function BaselineButtonPushed(app, event)
            
            try
                app.matBaseline = readWaveform(app);
            catch
                app.BaselineLamp.Color = [0.85,0.33,0.10];
                return
            end

            app.BaselineLamp.Color = "Green";

        end

        % Button pushed function: ReferenceButton
        function ReferenceButtonPushed(app, event)
            
            try
                app.matReference = readWaveform(app);
            catch
                app.ReferenceLamp.Color = [0.85,0.33,0.10];
                return
            end

            app.ReferenceLamp.Color = "Green";
        end

        % Button pushed function: PumpReferenceButton
        function PumpReferenceButtonPushed(app, event)
            
            try
                app.matPumpRef = readWaveform(app);
            catch
                app.PumpLamp.Color = [0.85,0.33,0.10];
                return
            end

            app.PumpLamp.Color = "Green";
        end

        % Button pushed function: RemoveBaselineButton
        function RemoveBaselineButtonPushed(app, event)
            app.matBaseline = [];
            app.BaselineLamp.Color = [0.85,0.33,0.10];
        end

        % Button pushed function: RemoveReferenceButton
        function RemoveReferenceButtonPushed(app, event)
            app.matReference = [];
            app.ReferenceLamp.Color = [0.85,0.33,0.10];
        end

        % Button pushed function: RemovePumpReferenceButton
        function RemovePumpReferenceButtonPushed(app, event)
            app.matPumpRef = [];
            app.PumpLamp.Color = [0.85,0.33,0.10];
        end

        % Value changed function: ModeSwitch
        function ModeSwitchValueChanged(app, event)
            value = app.ModeSwitch.Value;

            if isequal(value,'Count')
                app.TimesecEditField.Editable = "off";
                app.AcqusitionCountEditField.Editable = "on";
            else % time mode
                app.TimesecEditField.Editable = "on";
                app.AcqusitionCountEditField.Editable = "off";
            end

            app.TimesecEditField.Value = 0;
            app.AcqusitionCountEditField.Value = 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.AcquisitionDialogUIFigure = uifigure('Visible', 'off');
            app.AcquisitionDialogUIFigure.Name = 'TeraSmart Control';
            app.AcquisitionDialogUIFigure.Icon = fullfile(pathToMLAPP, 'CaT_logo.png');
            app.AcquisitionDialogUIFigure.CloseRequestFcn = createCallbackFcn(app, @AcquisitionDialogUIFigureCloseRequest, true);
            app.ReadStatusButton = uibutton(app.AcquisitionDialogUIFigure, 'push');
            app.ReadStatusButton.Text = 'Read Status';
            app.Image = uiimage(app.AcquisitionDialogUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'MENLO-Logo.png');
            app.EditField = uieditfield(app.AcquisitionDialogUIFigure, 'text');
            app.EditField.Editable = 'off';
            app.ReferenceAcquisitionPanel = uipanel(app.AcquisitionDialogUIFigure);
            app.ReferenceAcquisitionPanel.Title = 'Reference Acquisition';
            app.BaselineButton = uibutton(app.ReferenceAcquisitionPanel, 'push');
            app.BaselineButton.ButtonPushedFcn = createCallbackFcn(app, @BaselineButtonPushed, true);
            app.BaselineButton.Text = 'Baseline';
            app.RemoveBaselineButton = uibutton(app.ReferenceAcquisitionPanel, 'push');
            app.RemoveBaselineButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveBaselineButtonPushed, true);
            app.RemoveBaselineButton.Text = 'Remove Baseline';
            app.ReferenceButton = uibutton(app.ReferenceAcquisitionPanel, 'push');
            app.ReferenceButton.ButtonPushedFcn = createCallbackFcn(app, @ReferenceButtonPushed, true);
            app.ReferenceButton.Text = 'Reference';
            app.RemoveReferenceButton = uibutton(app.ReferenceAcquisitionPanel, 'push');
            app.RemoveReferenceButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveReferenceButtonPushed, true);
            app.RemoveReferenceButton.Text = 'Remove Reference';
            app.PumpReferenceButton = uibutton(app.ReferenceAcquisitionPanel, 'push');
            app.PumpReferenceButton.ButtonPushedFcn = createCallbackFcn(app, @PumpReferenceButtonPushed, true);
            app.PumpReferenceButton.Visible = 'off';
            app.PumpReferenceButton.Text = 'Pump Reference';
            app.RemovePumpReferenceButton = uibutton(app.ReferenceAcquisitionPanel, 'push');
            app.RemovePumpReferenceButton.ButtonPushedFcn = createCallbackFcn(app, @RemovePumpReferenceButtonPushed, true);
            app.RemovePumpReferenceButton.Visible = 'off';
            app.RemovePumpReferenceButton.Text = 'Remove Pump Reference';
            app.PumpCheckBox = uicheckbox(app.ReferenceAcquisitionPanel);
            app.PumpCheckBox.ValueChangedFcn = createCallbackFcn(app, @PumpCheckBoxValueChanged, true);
            app.PumpCheckBox.Text = 'Pump';
            app.SubtractBaselineCheckBox = uicheckbox(app.ReferenceAcquisitionPanel);
            app.SubtractBaselineCheckBox.Text = 'Subtract Baseline';
            app.BaselineLampLabel = uilabel(app.ReferenceAcquisitionPanel);
            app.BaselineLampLabel.Text = 'Baseline';
            app.BaselineLamp = uilamp(app.ReferenceAcquisitionPanel);
            app.ReferenceLampLabel = uilabel(app.ReferenceAcquisitionPanel);
            app.ReferenceLampLabel.Text = 'Reference';
            app.ReferenceLamp = uilamp(app.ReferenceAcquisitionPanel);
            app.PumpLampLabel = uilabel(app.ReferenceAcquisitionPanel);
            app.PumpLampLabel.Visible = 'off';
            app.PumpLampLabel.Text = 'Pump';
            app.PumpLamp = uilamp(app.ReferenceAcquisitionPanel);
            app.PumpLamp.Visible = 'off';
            app.MeasurementDetailsPanel = uipanel(app.AcquisitionDialogUIFigure);
            app.MeasurementDetailsPanel.Title = 'Measurement Details';
            app.ACQUIREButton = uibutton(app.MeasurementDetailsPanel, 'push');
            app.ACQUIREButton.ButtonPushedFcn = createCallbackFcn(app, @ACQUIREButtonPushed, true);
            app.ACQUIREButton.Text = 'ACQUIRE';
            app.STOPButton = uibutton(app.MeasurementDetailsPanel, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.SampleEditFieldLabel = uilabel(app.MeasurementDetailsPanel);
            app.SampleEditFieldLabel.Text = 'Sample';
            app.SampleEditField = uieditfield(app.MeasurementDetailsPanel, 'text');
            app.DescriptionEditFieldLabel = uilabel(app.MeasurementDetailsPanel);
            app.DescriptionEditFieldLabel.Text = 'Description';
            app.DescriptionEditField = uieditfield(app.MeasurementDetailsPanel, 'text');
            app.ModeDropDownLabel = uilabel(app.MeasurementDetailsPanel);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.MeasurementDetailsPanel);
            app.ModeDropDown.Items = {'TX', 'RX'};
            app.ModeDropDown.Value = 'TX';
            app.Metadata1EditFieldLabel = uilabel(app.MeasurementDetailsPanel);
            app.Metadata1EditFieldLabel.Text = 'Metadata 1';
            app.Metadata1EditField = uieditfield(app.MeasurementDetailsPanel, 'text');
            app.NumericValueEditField_2Label = uilabel(app.MeasurementDetailsPanel);
            app.NumericValueEditField_2Label.Text = 'Numeric Value';
            app.NumericValueEditField_1 = uieditfield(app.MeasurementDetailsPanel, 'numeric');
            app.Metadata2EditFieldLabel = uilabel(app.MeasurementDetailsPanel);
            app.Metadata2EditFieldLabel.Text = 'Metadata 2';
            app.Metadata2EditField = uieditfield(app.MeasurementDetailsPanel, 'text');
            app.NumericValueEditFieldLabel = uilabel(app.MeasurementDetailsPanel);
            app.NumericValueEditFieldLabel.Text = 'Numeric Value';
            app.NumericValueEditField_2 = uieditfield(app.MeasurementDetailsPanel, 'numeric');
            app.THzLampLabel = uilabel(app.AcquisitionDialogUIFigure);
            app.THzLampLabel.Text = 'THz';
            app.THzLamp = uilamp(app.AcquisitionDialogUIFigure);
            app.SystemReadyLampLabel = uilabel(app.AcquisitionDialogUIFigure);
            app.SystemReadyLampLabel.Text = 'System Ready';
            app.SystemReadyLamp = uilamp(app.AcquisitionDialogUIFigure);
            app.MeasurementSettingsPanel = uipanel(app.AcquisitionDialogUIFigure);
            app.MeasurementSettingsPanel.Title = 'Measurement Settings';
            app.IntervalsecLabel = uilabel(app.MeasurementSettingsPanel);
            app.IntervalsecLabel.Text = 'Average Number';
            app.AverageNumberEditField = uieditfield(app.MeasurementSettingsPanel, 'numeric');
            app.AverageNumberEditField.Limits = [0 10000];
            app.AverageNumberEditField.ValueDisplayFormat = '%.0f';
            app.AverageNumberEditField.Value = 1;
            app.TimesecEditFieldLabel = uilabel(app.MeasurementSettingsPanel);
            app.TimesecEditFieldLabel.Text = 'Time (sec)';
            app.TimesecEditField = uieditfield(app.MeasurementSettingsPanel, 'numeric');
            app.TimesecEditField.Limits = [0 100000];
            app.TimesecEditField.ValueDisplayFormat = '%.0f';
            app.TimesecEditField.Editable = 'off';
            app.AcqusitionCountEditFieldLabel = uilabel(app.MeasurementSettingsPanel);
            app.AcqusitionCountEditFieldLabel.Text = 'Acqusition Count';
            app.AcqusitionCountEditField = uieditfield(app.MeasurementSettingsPanel, 'numeric');
            app.AcqusitionCountEditField.Limits = [0 100000];
            app.AcqusitionCountEditField.ValueDisplayFormat = '%.0f';
            app.AcqusitionCountEditField.Editable = 'off';
            app.AcqusitionCountEditField.Value = 1;
            app.CurrentCountEditFieldLabel = uilabel(app.MeasurementSettingsPanel);
            app.CurrentCountEditFieldLabel.Text = 'Current Count  ';
            app.CurrentCountEditField = uieditfield(app.MeasurementSettingsPanel, 'numeric');
            app.CurrentCountEditField.Limits = [0 Inf];
            app.CurrentCountEditField.ValueDisplayFormat = '%.0f';
            app.CurrentCountEditField.Editable = 'off';
            app.TimeLeftsecEditFieldLabel = uilabel(app.MeasurementSettingsPanel);
            app.TimeLeftsecEditFieldLabel.Text = 'Time Left (sec)';
            app.TimeLeftsecEditField = uieditfield(app.MeasurementSettingsPanel, 'numeric');
            app.TimeLeftsecEditField.Limits = [0 Inf];
            app.TimeLeftsecEditField.ValueDisplayFormat = '%5.2f';
            app.TimeLeftsecEditField.Editable = 'off';
            app.ModeSwitchLabel = uilabel(app.MeasurementSettingsPanel);
            app.ModeSwitchLabel.Text = 'Mode';
            app.ModeSwitch = uiswitch(app.MeasurementSettingsPanel, 'slider');
            app.ModeSwitch.Items = {'Count', 'Time'};
            app.ModeSwitch.ValueChangedFcn = createCallbackFcn(app, @ModeSwitchValueChanged, true);
            app.ModeSwitch.Value = 'Count';
            % Show the figure after all components are created
            app.AcquisitionDialogUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AcquisitionDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AcquisitionDialogUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AcquisitionDialogUIFigure)
        end
    end
end